classdef GUI_Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Panel_2     matlab.ui.container.Panel
        UIAxes_2    matlab.ui.control.UIAxes
        UIAxes      matlab.ui.control.UIAxes
        Panel       matlab.ui.container.Panel
        Button_3    matlab.ui.control.Button
        Button_2    matlab.ui.control.Button
        HzTextArea  matlab.ui.control.TextArea
        Label_3     matlab.ui.control.Label
        Button      matlab.ui.control.Button
        Label       matlab.ui.control.Label
    end

    
    properties (Access = private)
        X;
        t;
        f;
        P1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav'}, '选择一个音频文件');
            if isequal(file,0)
                disp('用户取消了选择');
            else
                % 清空操作
                cla(app.UIAxes);    % 清空坐标区 1
                cla(app.UIAxes_2);  % 清空坐标区 2
                app.HzTextArea.Value = '';  % 清空文本区域

                fullpath = fullfile(path, file);
                [X, Fs] = audioread(fullpath);
                disp(['你选择的文件是: ', fullpath]);
                app.X = X(:,1); % 取音频单通道
                T = 1/Fs;       % 采样周期，Fs为采样频率
                L = length(X);  % 信号长度
                app.t = (0:L-1)*T; % 时间向量
    
                % 频域分析
                Y = fft(X);
                P2 = abs(Y/L);    % 双边谱
                app.P1 = P2(1:L/2+1); % 单边谱
                app.P1(2:end-1) = 2*app.P1(2:end-1);
                app.f = Fs/L*(0:(L/2));
                
                % 获取主频峰位置
                [~, idx] = max(app.P1); % idx为频率索引index，忽略最大值本身
                app.HzTextArea.Value = sprintf('%.2f', app.f(idx));
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % 绘制时域波形图
            plot(app.UIAxes, app.t, app.X, 'LineWidth', 1);
            title(app.UIAxes, '时域波形图');
            xlabel(app.UIAxes, '时间（s）');
            ylabel(app.UIAxes, '振幅');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % 绘制频域波形图
            plot(app.UIAxes_2, app.f, app.P1, 'LineWidth', 1); 
            xlim(app.UIAxes_2, [0,1000]); % 只显示1kHz以内的频谱
            title(app.UIAxes_2, '单边幅度谱');
            xlabel(app.UIAxes_2, '频率（Hz）');
            ylabel(app.UIAxes_2, '|P1(f)|');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '信号与系统小组讨论 - 分析男生女生信号的频谱';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '用户操作';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选取音频文件';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '主频峰位置（Hz）';
            app.HzTextArea = uitextarea(app.Panel);
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '绘制时域波形图';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '绘制频域波形图';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '图像输出';
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, '时域频谱图')
            xlabel(app.UIAxes, '时间（s）')
            ylabel(app.UIAxes, '振幅')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Panel_2);
            title(app.UIAxes_2, '频域频谱图')
            xlabel(app.UIAxes_2, '频率（Hz）')
            ylabel(app.UIAxes_2, '|P(f)|')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end